classdef MA2_S2119032_Lai_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BroswerButton                  matlab.ui.control.Button
        pathTextArea                   matlab.ui.control.TextArea
        DelaybasedModulatorLabel       matlab.ui.control.Label
        ComputeplayButton_comfb        matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        FlangerCombLabel               matlab.ui.control.Label
        StereoChorusLabel              matlab.ui.control.Label
        StrengthKnobLabel              matlab.ui.control.Label
        StrengthKnob                   matlab.ui.control.Knob
        bufferinsecondKnobLabel        matlab.ui.control.Label
        bufferinsecondKnob             matlab.ui.control.Knob
        WhenN0programusenaivefloorforbufferlineindexLabel  matlab.ui.control.Label
        StartfromhereBrosweaudiofileLabel  matlab.ui.control.Label
        frequencyL1KnobLabel           matlab.ui.control.Label
        frequencyL1Knob                matlab.ui.control.Knob
        DepthL1KnobLabel               matlab.ui.control.Label
        DepthL1Knob                    matlab.ui.control.Knob
        DepthL2KnobLabel               matlab.ui.control.Label
        DepthL2Knob                    matlab.ui.control.Knob
        DepthR1KnobLabel               matlab.ui.control.Label
        DepthR1Knob                    matlab.ui.control.Knob
        DepthR2KnobLabel               matlab.ui.control.Label
        DepthR2Knob                    matlab.ui.control.Knob
        SwingL1KnobLabel               matlab.ui.control.Label
        SwingL1Knob                    matlab.ui.control.Knob
        SwingL2KnobLabel               matlab.ui.control.Label
        SwingL2Knob                    matlab.ui.control.Knob
        SwingR1KnobLabel               matlab.ui.control.Label
        SwingR1Knob                    matlab.ui.control.Knob
        SwingR2KnobLabel               matlab.ui.control.Label
        SwingR2Knob                    matlab.ui.control.Knob
        StrengthL1KnobLabel            matlab.ui.control.Label
        StrengthL1Knob                 matlab.ui.control.Knob
        StrengthL2KnobLabel            matlab.ui.control.Label
        StrengthL2Knob                 matlab.ui.control.Knob
        StrengthR1KnobLabel            matlab.ui.control.Label
        StrengthR1Knob                 matlab.ui.control.Knob
        StrengthR2KnobLabel            matlab.ui.control.Label
        StrengthR2Knob                 matlab.ui.control.Knob
        swingtypeSwitchLabel           matlab.ui.control.Label
        swingtypeSwitch                matlab.ui.control.Switch
        frequencyL2KnobLabel           matlab.ui.control.Label
        frequencyL2Knob                matlab.ui.control.Knob
        frequencyR1KnobLabel           matlab.ui.control.Label
        frequencyR1Knob                matlab.ui.control.Knob
        frequencyR2KnobLabel           matlab.ui.control.Label
        frequencyR2Knob                matlab.ui.control.Knob
        ComputeplayButton_flanger      matlab.ui.control.Button
        ComputeplayButton_chorus       matlab.ui.control.Button
        CombfbLabel                    matlab.ui.control.Label
        FlangerLabel                   matlab.ui.control.Label
        ChorusLabel                    matlab.ui.control.Label
        ComputeplayButton_combff       matlab.ui.control.Button
        StopButton_combff              matlab.ui.control.Button
        CombffLabel                    matlab.ui.control.Label
        frequencyforFlangerKnobLabel   matlab.ui.control.Label
        frequencyforFlangerKnob        matlab.ui.control.Knob
        NumberofIntepolationEditFieldLabel  matlab.ui.control.Label
        NumberofIntepolationEditField  matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BroswerButton
        function BroswerButtonPushed(app, event)
            [audio,path]=uigetfile('*.wav');
            audio_path=[path,audio];
            if (audio_path ~= "")
                app.pathTextArea.Value=audio_path; 
            end    
        end

        % Button pushed function: ComputeplayButton_combff
        function ComputeplayButton_combffPushed(app, event)
            %define the strength of the effect as g
            g        = app.StrengthKnob.Value;
            
            %define the delay time in seconds
            t        = app.bufferinsecondKnob.Value;
            
            %import audio
            [x,Fs]=audioread(char(app.pathTextArea.Value));
            
            %transfer to stereo audio
            if size(x,2)==2
                x=0.5*x(:,1)+0.5*x(:,2);
            end
            
            %Length of original x
            L        = length(x);
            
            %calculate the delay sample M
            M        = round(t*Fs);
            
            %pre-allocate delay-line buffer as dlinebuf;
            dlinebuf = zeros(M,1);
            
            %create output vector of feedforward and feedback operations
            y_ff     = zeros(L,1);
            
            %Circular_buffer:for loop to create y_ff vector
            for n = 1:L
                y_ff(n)   = dlinebuf(mod(n-1,M)+1)*g+x(n);
                %update buffer
                dlinebuf(mod((n+M-2),M)+1) = x(n);
            end
            %play
            sound(y_ff,Fs)
        end

        % Button pushed function: ComputeplayButton_comfb
        function ComputeplayButton_comfbPushed(app, event)
            %define the strength of the effect as g
            g        = app.StrengthKnob.Value;
            
            %define the delay time in seconds
            t        = app.bufferinsecondKnob.Value;
            
            %import audio
            [x,Fs]=audioread(char(app.pathTextArea.Value));
            
            %transfer to stereo audio
            if size(x,2)==2
                x=0.5*x(:,1)+0.5*x(:,2);
            end
            
            %Length of original x
            L        = length(x);
            
            %calculate the delay sample M
            M        = round(t*Fs);
            
            %pre-allocate delay-line buffer as dlinebuf;
            dlinebuf = zeros(M,1);
            
            %create output vector of feedforward and feedback operations
            y_fb     = zeros(L,1);
            
            %for loop to create y_fb vector
            for n = 1:L
                y_fb(n)   = -dlinebuf(M)*g+x(n);
                %update buffer
                dlinebuf(M) = y_fb(n);
                dlinebuf    = circshift(dlinebuf,1);
            end
            %play
            sound(y_fb,Fs)
        end

        % Button pushed function: ComputeplayButton_flanger
        function ComputeplayButton_flangerPushed(app, event)
            function mtx = MA2_S2119032_Lai_Linterp(N,Q)
            %assert if N is an even number
            if mod(N,2)~=0
            fprintf('please enter an even integer N.....');
            assert(mod(N,2)==0);
            end
            %calculate the interpolation matrix
            %define zeros matrix with Q row and N colume
            mtx = zeros(Q,N);
            % row index
            q   = 1:Q;
            % colume index
            n   = 1:N;
            % values vector of alpha
            a_q = (-Q/2+q-1)/Q;
            % coefficient of the N Lagrange polynomials
            p   = -(N-1)/2:1:(N-1)/2;
            % for loop to create matrix
            for j = n
                for i = q
                %expression of matrix
                mtx(i,j) = prod(a_q(i)-p(p~=p(j)))/prod(-p(p~=p(j))+p(j));
                end
            end
            end
            
            %define the parameter for Lagrange interpolation
            N        = app.NumberofIntepolationEditField.Value; % N must be even
            Q        = 100;
            %recall the Lagrange interpolation matrix
            mtx      = MA2_S2119032_Lai_Linterp(N,Q);
            
            %define the strength of the effect as g
            g        = app.StrengthKnob.Value;
            
            %define the delay time in seconds
            t        = app.bufferinsecondKnob.Value;
            
            %coefficient of sine
            a=1;
            
            %import audio
            [x,Fs]=audioread(char(app.pathTextArea.Value));
            
            %transfer to stereo audio
            if size(x,2)==2
                x=0.5*x(:,1)+0.5*x(:,2);
            end
            
            %length of x;
            L        = length(x);
            
            %calculate the delay sample M
            MO       = round(t*Fs/2);
            
            %calculate the delay, keep every interpolation sample have N-1 neighbor
            delay    = MO*2+N+1;
            
            %frequency of LFO
            fO       = app.frequencyforFlangerKnob.Value;
             
            %M vector
            M        = (MO*(1+a*sin(2*pi*fO*(0:L-1)/Fs))).';
            
            % row index
            q   = 1:Q;
            
            %introduce alpha vector based on Q
            a_q      = (-Q/2+q-1)/Q;
            
            %Nearest neighbor vector
            NB       = 1-N/2:N/2;
            
            %create zeros value vector
            value    = zeros(N,1);
            
            %zeros padding;
            dlinebuf = zeros(delay,1);
            
            %pre-allocate output vector of feedback operations
            y_ff     = zeros(L,1);
            
            if N~=0
                %Interpolation Method, Circular buffer：for loop to create y_ff vector
                for n = 1:L
                    %calculate alpha
                    alpha    = M(n)-floor(M(n))-1/2;
                    %read the row from table
                    [~,index] = sort(abs(a_q-alpha));
                    coef      = mtx(index(1),:);
                    %introduce weighted sum
                    wsum=0;
                    %for loop to create weighted sum
                    for i = 1:N
                        value(i)= dlinebuf(mod(mod(n-1,delay)+N/2+NB(i),delay)+1);
                        wsum = wsum + value(i)*coef(i);
                    end
                    %read the weighted sum from the delay-line buffer corresponding to the
                    %current delay-length and sum up with x(n)
                    y_ff(n)   = wsum*g+x(n);
                    %update delay line buffer
                    dlinebuf(mod((n+delay-2),delay)+1) = x(n);
                end
            else
                %naive round, Circular shift：for loop to create y_ff vector
                for n = 1:L
                    y_ff(n)   = dlinebuf(floor(M(n))+1)*g+x(n);
                    dlinebuf(delay) = x(n);
                    dlinebuf    = circshift(dlinebuf,1);
                end
            end
            
            %play the sound
            sound(y_ff,Fs)
        end

        % Button pushed function: ComputeplayButton_chorus
        function ComputeplayButton_chorusPushed(app, event)
            function mtx = MA2_S2119032_Lai_Linterp(N,Q)
            %assert if N is an even number
            if mod(N,2)~=0
            fprintf('please enter an even integer N.....');
            assert(mod(N,2)==0);
            end
            %calculate the interpolation matrix
            %define zeros matrix with Q row and N colume
            mtx = zeros(Q,N);
            % row index
            q   = 1:Q;
            % colume index
            n   = 1:N;
            % values vector of alpha
            a_q = (-Q/2+q-1)/Q;
            % coefficient of the N Lagrange polynomials
            p   = -(N-1)/2:1:(N-1)/2;
            % for loop to create matrix
            for j = n
                for i = q
                %expression of matrix
                mtx(i,j) = prod(a_q(i)-p(p~=p(j)))/prod(-p(p~=p(j))+p(j));
                end
            end
            end
            %define the parameter for Lagrange interpolation
            N        = app.NumberofIntepolationEditField.Value; %N must be even
            Q        = 100;
            
            %recall the Lagrange interpolation matrix
            mtx      = MA2_S2119032_Lai_Linterp(N,Q);
            
            %import audio
            [x,Fs]   = audioread(char(app.pathTextArea.Value));
            
            %define buffer time for LFO1~4 
            %LFO1 2 is for left channel,LFO3 4 is for right channel
            t1       = app.DepthL1Knob.Value;
            t2       = app.DepthL2Knob.Value;
            t3       = app.DepthR1Knob.Value;
            t4       = app.DepthR1Knob.Value;
            t=[t1,t2,t3,t4];
            
            PO       = zeros(4,1);
            
            %effect depth for LFO 
            for l = 1:4
                PO(l) = round(t(l)/2*Fs);
            end
            
            %effect oscillation/swing range for LFO 1 2 3 4
            D1       = round(t1*app.SwingL1Knob.Value*Fs);
            D2       = round(t2*app.SwingL2Knob.Value*Fs);
            D3       = round(t3*app.SwingR1Knob.Value*Fs);
            D4       = round(t4*app.SwingR2Knob.Value*Fs);
            D        = [D1,D2,D3,D4];
            
            %LFO frequency for LFO 1 2 3 4
            f1       = app.frequencyL1Knob.Value;
            f2       = app.frequencyL2Knob.Value;
            f3       = app.frequencyR1Knob.Value;
            f4       = app.frequencyR2Knob.Value;
            f        = [f1,f2,f3,f4];
            
            %effect strength, set between -1 and 1, for LFO 1 2 3 4
            g1       = app.StrengthL1Knob.Value;
            g2       = app.StrengthL2Knob.Value;
            g3       = app.StrengthR1Knob.Value;
            g4       = app.StrengthR2Knob.Value;
            g        = [g1,g2,g3,g4];
            
            %define the delay time in seconds
            t        = max(t);
            
            %calculate the delay
            delay    = round(t*Fs/2)*2;
            
            %length of x;
            L        = length(x);
            
            %initialize M vector
            M        = zeros(L,4);
            
            %initialize m vector  random signal
            m        = zeros(4,L);
            
            switch app.swingtypeSwitch.Value
                case 'sine'
                    for l=1:4
                        m(l,:)=randomsignal(L,Fs,f(l));
                        %Normalise 
                        m(l,:)=(D(l)/2)/max(abs(m(l,:)))*m(l,:);
                        %final signal for M
                        M(:,l)=((PO(l)-max(m(l,:)))+m(l,:))';
                    end
                case 'random'
                    for l=1:4
                        M(:,l)  = (PO(l)*(1+D(l)/PO(l)*sin(2*pi*f(l)*(0:L-1)/Fs))).';
                    end
            end
                
            % row index
            q   = 1:Q;
            
            %introduce alpha vector based on Q
            a_q      = (-Q/2+q-1)/Q;
            
            %Nearest neighbor vector
            NB       = 1-N/2:N/2;
            
            %create delay buffer 1;
            dlinebuf  = zeros(delay,1);
            
            %pre-allocate output vector of feedback operations
            y_L        = zeros(L,1);
            y_R        = zeros(L,1);
            
            %initialize vector for loop filling in 
            alpha    = zeros(4,1);
            coef     = zeros(4,N);
            index    = zeros(4,Q);
            value    = zeros(N,4);
            
            if N ~= 0
                %for loop to create left channel output
                for n = 1:L
                    %wsum return to zero for each n
                    wsum     = zeros(4,1);
                    for l = 1:4
                        %calculate alpha
                        alpha(l)       = M(n,l)-floor(M(n,l))-1/2;
                        %read the row from table
                        [~,index(l,:)] = sort(abs(a_q-alpha(l)));
                        coef(l,:)      = mtx(index(l,1),:);
                        %calculate wsum vector for each n
                        for i = 1:N
                            value(i,l) = dlinebuf(floor(M(n,l))+NB(i)+N/2);
                            wsum(l)    = wsum(l) + value(i,l)*coef(l,i);
                        end
                    end
                    %calculate Left and right channel seperately
                    y_L(n)             = wsum(1)*g(1)+wsum(2)*g(2)+x(n);
                    y_R(n)             = wsum(3)*g(3)+wsum(4)*g(4)+x(n);
                    %update delay buffer1
                    dlinebuf(delay)    = x(n);
                    dlinebuf           = circshift(dlinebuf,1);
                end
            else
                %circshift,naive round for dlinebuf index
                for n=1:L
                    for l = 1:4
                    y_L(n)   =dlinebuf(floor(M(n,1))+1)*g(1)+dlinebuf(floor(M(n,1))+1)*g(2)+x(n);
                    y_R(n)   =dlinebuf(floor(M(n,2))+1)*g(3)+dlinebuf(floor(M(n,2))+1)*g(4)+x(n);
                    end
                %update delay buffer
                dlinebuf(delay) = x(n);
                dlinebuf    = circshift(dlinebuf,1);
                end
            end
            %combine L R to one matrix
            y(:,1)=y_L;
            y(:,2)=y_R;
            %play the sound
            sound(y,Fs)
        end

        % Button pushed function: StopButton_combff
        function StopButton_combffPushed(app, event)
            clear sound
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BroswerButton = uibutton(app.UIFigure, 'push');
            app.BroswerButton.ButtonPushedFcn = createCallbackFcn(app, @BroswerButtonPushed, true);
            app.BroswerButton.Text = 'Broswer';
            app.pathTextArea = uitextarea(app.UIFigure);
            app.pathTextArea.Value = {'mozart.wav'};
            app.DelaybasedModulatorLabel = uilabel(app.UIFigure);
            app.DelaybasedModulatorLabel.Text = {'Delay based '; 'Modulator'};
            app.ComputeplayButton_comfb = uibutton(app.UIFigure, 'push');
            app.ComputeplayButton_comfb.ButtonPushedFcn = createCallbackFcn(app, @ComputeplayButton_comfbPushed, true);
            app.ComputeplayButton_comfb.Text = 'Compute/play';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FlangerCombLabel = uilabel(app.UIFigure);
            app.FlangerCombLabel.Text = 'Flanger/Comb';
            app.StereoChorusLabel = uilabel(app.UIFigure);
            app.StereoChorusLabel.Text = {'Stereo '; 'Chorus'};
            app.StrengthKnobLabel = uilabel(app.UIFigure);
            app.StrengthKnobLabel.Text = 'Strength';
            app.StrengthKnob = uiknob(app.UIFigure, 'continuous');
            app.StrengthKnob.Limits = [-1 1];
            app.StrengthKnob.Value = 0.7;
            app.bufferinsecondKnobLabel = uilabel(app.UIFigure);
            app.bufferinsecondKnobLabel.Text = 'buffer in second';
            app.bufferinsecondKnob = uiknob(app.UIFigure, 'continuous');
            app.bufferinsecondKnob.Limits = [0.002 0.1];
            app.bufferinsecondKnob.Value = 0.1;
            app.WhenN0programusenaivefloorforbufferlineindexLabel = uilabel(app.UIFigure);
            app.WhenN0programusenaivefloorforbufferlineindexLabel.Text = {'-When N = 0, program use naive floor for buffer index'; '-N should be 0 or even integer.'};
            app.StartfromhereBrosweaudiofileLabel = uilabel(app.UIFigure);
            app.StartfromhereBrosweaudiofileLabel.Text = 'Start from here! Broswe audio file';
            app.frequencyL1KnobLabel = uilabel(app.UIFigure);
            app.frequencyL1KnobLabel.Text = 'frequency L1';
            app.frequencyL1Knob = uiknob(app.UIFigure, 'continuous');
            app.frequencyL1Knob.Limits = [0.2 5];
            app.frequencyL1Knob.Value = 1;
            app.DepthL1KnobLabel = uilabel(app.UIFigure);
            app.DepthL1KnobLabel.Text = 'Depth L1';
            app.DepthL1Knob = uiknob(app.UIFigure, 'continuous');
            app.DepthL1Knob.Limits = [0.002 0.1];
            app.DepthL1Knob.Value = 0.08;
            app.DepthL2KnobLabel = uilabel(app.UIFigure);
            app.DepthL2KnobLabel.Text = 'Depth L2';
            app.DepthL2Knob = uiknob(app.UIFigure, 'continuous');
            app.DepthL2Knob.Limits = [0.002 0.1];
            app.DepthL2Knob.Value = 0.05;
            app.DepthR1KnobLabel = uilabel(app.UIFigure);
            app.DepthR1KnobLabel.Text = 'Depth R1';
            app.DepthR1Knob = uiknob(app.UIFigure, 'continuous');
            app.DepthR1Knob.Limits = [0.002 0.1];
            app.DepthR1Knob.Value = 0.03;
            app.DepthR2KnobLabel = uilabel(app.UIFigure);
            app.DepthR2KnobLabel.Text = 'Depth R2';
            app.DepthR2Knob = uiknob(app.UIFigure, 'continuous');
            app.DepthR2Knob.Limits = [0.002 0.1];
            app.DepthR2Knob.Value = 0.07;
            app.SwingL1KnobLabel = uilabel(app.UIFigure);
            app.SwingL1KnobLabel.Text = 'Swing L1';
            app.SwingL1Knob = uiknob(app.UIFigure, 'continuous');
            app.SwingL1Knob.Limits = [0 1];
            app.SwingL1Knob.Value = 0.5;
            app.SwingL2KnobLabel = uilabel(app.UIFigure);
            app.SwingL2KnobLabel.Text = 'Swing L2';
            app.SwingL2Knob = uiknob(app.UIFigure, 'continuous');
            app.SwingL2Knob.Limits = [0 1];
            app.SwingL2Knob.Value = 0.2;
            app.SwingR1KnobLabel = uilabel(app.UIFigure);
            app.SwingR1KnobLabel.Text = 'Swing R1';
            app.SwingR1Knob = uiknob(app.UIFigure, 'continuous');
            app.SwingR1Knob.Limits = [0 1];
            app.SwingR1Knob.Value = 0.3;
            app.SwingR2KnobLabel = uilabel(app.UIFigure);
            app.SwingR2KnobLabel.Text = 'Swing R2';
            app.SwingR2Knob = uiknob(app.UIFigure, 'continuous');
            app.SwingR2Knob.Limits = [-1 1];
            app.SwingR2Knob.Value = -0.4;
            app.StrengthL1KnobLabel = uilabel(app.UIFigure);
            app.StrengthL1KnobLabel.Text = 'Strength L1';
            app.StrengthL1Knob = uiknob(app.UIFigure, 'continuous');
            app.StrengthL1Knob.Limits = [-1 1];
            app.StrengthL1Knob.Value = 0.7;
            app.StrengthL2KnobLabel = uilabel(app.UIFigure);
            app.StrengthL2KnobLabel.Text = 'Strength L2';
            app.StrengthL2Knob = uiknob(app.UIFigure, 'continuous');
            app.StrengthL2Knob.Limits = [-1 1];
            app.StrengthL2Knob.Value = 0.3;
            app.StrengthR1KnobLabel = uilabel(app.UIFigure);
            app.StrengthR1KnobLabel.Text = 'Strength R1';
            app.StrengthR1Knob = uiknob(app.UIFigure, 'continuous');
            app.StrengthR1Knob.Limits = [0.002 0.1];
            app.StrengthR1Knob.Value = 0.07;
            app.StrengthR2KnobLabel = uilabel(app.UIFigure);
            app.StrengthR2KnobLabel.Text = 'Strength R2';
            app.StrengthR2Knob = uiknob(app.UIFigure, 'continuous');
            app.StrengthR2Knob.Limits = [-1 1];
            app.StrengthR2Knob.Value = 0.8;
            app.swingtypeSwitchLabel = uilabel(app.UIFigure);
            app.swingtypeSwitchLabel.Text = 'swing type';
            app.swingtypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.swingtypeSwitch.Items = {'Sine', 'random'};
            app.swingtypeSwitch.Value = 'Sine';
            app.frequencyL2KnobLabel = uilabel(app.UIFigure);
            app.frequencyL2KnobLabel.Text = 'frequency L2';
            app.frequencyL2Knob = uiknob(app.UIFigure, 'continuous');
            app.frequencyL2Knob.Limits = [0.2 5];
            app.frequencyL2Knob.Value = 2;
            app.frequencyR1KnobLabel = uilabel(app.UIFigure);
            app.frequencyR1KnobLabel.Text = 'frequency R1';
            app.frequencyR1Knob = uiknob(app.UIFigure, 'continuous');
            app.frequencyR1Knob.Limits = [0.2 5];
            app.frequencyR1Knob.Value = 3;
            app.frequencyR2KnobLabel = uilabel(app.UIFigure);
            app.frequencyR2KnobLabel.Text = 'frequency R2';
            app.frequencyR2Knob = uiknob(app.UIFigure, 'continuous');
            app.frequencyR2Knob.Limits = [0.2 5];
            app.frequencyR2Knob.Value = 4;
            app.ComputeplayButton_flanger = uibutton(app.UIFigure, 'push');
            app.ComputeplayButton_flanger.ButtonPushedFcn = createCallbackFcn(app, @ComputeplayButton_flangerPushed, true);
            app.ComputeplayButton_flanger.Text = 'Compute/play';
            app.ComputeplayButton_chorus = uibutton(app.UIFigure, 'push');
            app.ComputeplayButton_chorus.ButtonPushedFcn = createCallbackFcn(app, @ComputeplayButton_chorusPushed, true);
            app.ComputeplayButton_chorus.Text = 'Compute/play';
            app.CombfbLabel = uilabel(app.UIFigure);
            app.CombfbLabel.Text = 'Comb fb';
            app.FlangerLabel = uilabel(app.UIFigure);
            app.FlangerLabel.Text = 'Flanger';
            app.ChorusLabel = uilabel(app.UIFigure);
            app.ChorusLabel.Text = 'Chorus';
            app.ComputeplayButton_combff = uibutton(app.UIFigure, 'push');
            app.ComputeplayButton_combff.ButtonPushedFcn = createCallbackFcn(app, @ComputeplayButton_combffPushed, true);
            app.ComputeplayButton_combff.Text = 'Compute/play';
            app.StopButton_combff = uibutton(app.UIFigure, 'push');
            app.StopButton_combff.ButtonPushedFcn = createCallbackFcn(app, @StopButton_combffPushed, true);
            app.StopButton_combff.Text = 'Stop';
            app.CombffLabel = uilabel(app.UIFigure);
            app.CombffLabel.Text = 'Comb ff';
            app.frequencyforFlangerKnobLabel = uilabel(app.UIFigure);
            app.frequencyforFlangerKnobLabel.Text = 'frequency  for Flanger';
            app.frequencyforFlangerKnob = uiknob(app.UIFigure, 'continuous');
            app.frequencyforFlangerKnob.Limits = [0.2 5];
            app.frequencyforFlangerKnob.Value = 2;
            app.NumberofIntepolationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIntepolationEditFieldLabel.Text = 'Number of Intepolation ';
            app.NumberofIntepolationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIntepolationEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MA2_S2119032_Lai_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end